classdef Separation_aveugle_de_sources < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SparationaveugledesourcesUIFigure  matlab.ui.Figure
        ChoixdesourceMenu               matlab.ui.container.Menu
        Source1Menu                     matlab.ui.container.Menu
        Source2Menu                     matlab.ui.container.Menu
        ParametrageMenu                 matlab.ui.container.Menu
        ReinitialiserlessourceMenu      matlab.ui.container.Menu
        ExempledematriceM2x2Menu        matlab.ui.container.Menu
        ExempledematriceM3x2Menu        matlab.ui.container.Menu
        ExempledematriceM5x2Menu        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Plot1Tab                        matlab.ui.container.Tab
        Mixed1_Plot                     matlab.ui.control.UIAxes
        Plot2Tab                        matlab.ui.container.Tab
        Mixed2_Plot                     matlab.ui.control.UIAxes
        Plot3Tab                        matlab.ui.container.Tab
        Mixed3_Plot                     matlab.ui.control.UIAxes
        Plot4Tab                        matlab.ui.container.Tab
        Mixed4_Plot                     matlab.ui.control.UIAxes
        Plot5Tab                        matlab.ui.container.Tab
        Mixed5_Plot                     matlab.ui.control.UIAxes
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        Valeurdei0Slider                matlab.ui.control.Slider
        Valeurdei0SliderLabel           matlab.ui.control.Label
        NombredepointSlider             matlab.ui.control.Slider
        NombredepointSliderLabel        matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        TraitementButton                matlab.ui.control.Button
        NombredecapteurSpinner          matlab.ui.control.Spinner
        NombredecapteurSpinnerLabel     matlab.ui.control.Label
        MatricealeatoirePanel           matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        capteursButton_3                matlab.ui.control.Button
        capteursButton_2                matlab.ui.control.Button
        capteursButton                  matlab.ui.control.Button
        MatricedemelangePanel           matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        GridLayout5                     matlab.ui.container.GridLayout
        VolumeSliderLabel               matlab.ui.control.Label
        VolumeSlider                    matlab.ui.control.Slider
        Lamp                            matlab.ui.control.Lamp
        EcouteButton                    matlab.ui.control.Button
        ChoixdelecouteDropDown          matlab.ui.control.DropDown
        ChoixdelecouteDropDownLabel     matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        GridLayout4                     matlab.ui.container.GridLayout
        Nomdelasource1TextArea          matlab.ui.control.TextArea
        Nomdelasource1TextAreaLabel     matlab.ui.control.Label
        Nomdelasource2TextArea          matlab.ui.control.TextArea
        Nomdelasource2TextAreaLabel     matlab.ui.control.Label
        AffichagedelasparationButtonGroup  matlab.ui.container.ButtonGroup
        Sourcespare2Button              matlab.ui.control.RadioButton
        Sourcespare1Button              matlab.ui.control.RadioButton
        AffichagedessourcesButtonGroup  matlab.ui.container.ButtonGroup
        Source2Button                   matlab.ui.control.RadioButton
        Source1Button                   matlab.ui.control.RadioButton
        PlotSourceSplit                 matlab.ui.control.UIAxes
        PlotSource                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function DisplayMessage(app, message)
            if strcmp(app.TextArea.Value,'')
                app.TextArea.Value = message;
            else
                app.TextArea.Value = [app.TextArea.Value(:)' , {message}];
            end
        end
        
        function [Phi] = Intercorrelation(app, x,y,N,i)
            memo = 0;
            for k=1:N
                if k-i>0
                    memo = memo + x(k)*y(k-i);
                end
            end
            Phi = memo/N;
        end

        function [Phi] = Autocorrelation(app, x,N,i)
            memo = 0;
            for k=1:N
                if k-i>0
                    memo = memo + x(k)*x(k-i);
                end
            end
            Phi = memo/N;
        end

        
        function UpdateMatrixM(app)
            global M;
            value = app.NombredecapteurSpinner.Value;
            M = [app.EditField.Value app.EditField_2.Value;
                app.EditField_3.Value app.EditField_4.Value];
            if value>=3
                M = [M;
                    app.EditField_5.Value app.EditField_6.Value];
            end
            if value>=4
                M = [M;
                    app.EditField_7.Value app.EditField_8.Value];
            end
            if value>=5
                M = [M;
                    app.EditField_9.Value app.EditField_10.Value];
            end
        end
        
        function [V,D] = eigtrivpd(app,  A, nr )
            % nr = 1 : normalisation de la norme des vecteurs par rapport à B : V'BV=I
            if nargin < 3
                nr = 0;
            end
            if nargin == 2
                [V,D] = eig(A);
            end
            D = diag(D);
            n = length(D);
            [Dord,ordre] = sort(D,'descend');
            for i=1:n
                Vord(:,i) = V(:,ordre(i));
            end
            V = Vord;
            D = Dord;
            
            if nr == 1
                for i = 1:n % pour avoir Wn'*Wn=Ip !! nécessaire ?
                    V(:,i) = V(:,i) / sqrt(V(:,i)'*B*V(:,i)) ;
                end
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global source1_name source1_data source2_name source2_data GammaY LambdaY_i0 Fe Ns Nc N t T M X Y Z;
            
            N = 40960;
            Ns = 2;
            Nc = 2;
            T = 5;

            Y = zeros(Nc,N);
            Z = zeros(Ns,N);
            
            data = load('handel.mat');
            source1_name = 'handel.mat';
            source1_data = data.y(1:N,1);
            set(app.Nomdelasource1TextArea, 'Value','');
            set(app.Nomdelasource1TextArea, 'Value',source1_name);

            data = load('laughter.mat');
            source2_name = 'laughter.mat';
            source2_data = data.y(1:N,1);
            set(app.Nomdelasource2TextArea, 'Value','');
            set(app.Nomdelasource2TextArea, 'Value',source2_name);

            Fe = data.Fs;
            t = [0:N-1]/Fe;
            plot(app.PlotSource, t, source1_data);
        end

        % Menu selected function: Source1Menu
        function Source1MenuSelected(app, event)
            global source1_name source1_data Fe N t;
            name = uigetfile('.wav');
            [data,fe] = audioread(name);
            if fe~=Fe
                msgbox('Mauvaise fréquence d echantillonnage');
            else
                source1_name = name;
                source1_data = data(1:N,1);
                set(app.Nomdelasource1TextArea, 'Value','');
                set(app.Nomdelasource1TextArea, 'Value',source1_name);
            end
            selectedButton = app.AffichagedessourcesButtonGroup.SelectedObject;
            text = selectedButton.Text;
            if text=='Source 1'
                plot(app.PlotSource, t, source1_data);
            end
        end

        % Menu selected function: Source2Menu
        function Source2MenuSelected(app, event)
            global source2_name source2_data Fe N t;
            name = uigetfile('.wav');
            [data,fe] = audioread(name);
            if fe~=Fe
                msgbox('Mauvaise fréquence d echantillonnage');
            else
                source2_name = name;
                source2_data = data(1:N,1);
                set(app.Nomdelasource2TextArea, 'Value','');
                set(app.Nomdelasource2TextArea, 'Value',source2_name);
            end
            selectedButton = app.AffichagedessourcesButtonGroup.SelectedObject;
            text = selectedButton.Text;
            if text=='Source 2'
                plot(app.PlotSource, t, source2_data);
            end
        end

        % Selection changed function: AffichagedessourcesButtonGroup
        function AffichagedessourcesButtonGroupSelectionChanged(app, event)
            global source1_data source2_data t;
            selectedButton = app.AffichagedessourcesButtonGroup.SelectedObject;
            text = selectedButton.Text;
            if text=='Source 1'
                plot(app.PlotSource, t, source1_data);
            elseif text=='Source 2'
                plot(app.PlotSource, t, source2_data);
            end
            DisplayMessage(app, text);
        end

        % Selection changed function: AffichagedelasparationButtonGroup
        function AffichagedelasparationButtonGroupSelectionChanged(app, event)
            global Z t;
            selectedButton = app.AffichagedelasparationButtonGroup.SelectedObject;
            text = selectedButton.Text;
            if text=='Source séparée 1'
                plot(app.PlotSourceSplit, t, Z(1,:));
            elseif text=='Source séparée 2'
                plot(app.PlotSourceSplit, t, Z(2,:));
            end
            DisplayMessage(app, text);
        end

        % Button pushed function: EcouteButton
        function EcouteButtonPushed(app, event)
            global source1_data source2_data Z Fe T Y;
            choix = app.ChoixdelecouteDropDown.Value;
            DisplayMessage(app, choix);
            if length(choix) == length('Source 1')
                if choix == 'Source 1'
                    musique = source1_data./max(source1_data);
                elseif choix == 'Source 2'
                    musique = source2_data./max(source2_data);
                elseif choix == 'Separe 1'
                    musique = Z(1,:)./max(Z(1,:));
                elseif choix == 'Separe 2'
                    musique = Z(2,:)./max(Z(2,:));
                end
            elseif length(choix) == length('Melange 1')
                if choix == 'Melange 1'
                    musique = Y(1,:)./max(Y(1,:));
                elseif choix == 'Melange 2'
                    musique = Y(2,:)./max(Y(2,:));
                elseif choix == 'Melange 3'
                    musique = Y(3,:)./max(Y(3,:));
                elseif choix == 'Melange 4'
                    musique = Y(4,:)./max(Y(4,:));
                elseif choix == 'Melange 5'
                    musique = Y(5,:)./max(Y(5,:));
                end
            end
            sound(app.VolumeSlider.Value * real(musique), Fe);
            app.Lamp.Enable = 'on';
            app.EcouteButton.Enable = 'off';
            pause(T);
            app.Lamp.Enable = 'off';
            app.EcouteButton.Enable = 'on';
        end

        % Value changed function: NombredecapteurSpinner
        function NombredecapteurSpinnerValueChanged(app, event)
            value = app.NombredecapteurSpinner.Value;

            app.capteursButton.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_2.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_3.BackgroundColor = [0.96,0.96,0.96];
            if value==2
                app.EditField_5.Enable = 'off';
                app.EditField_6.Enable = 'off';
                app.EditField_7.Enable = 'off';
                app.EditField_8.Enable = 'off';
                app.EditField_9.Enable = 'off';
                app.EditField_10.Enable = 'off';
                app.capteursButton.BackgroundColor = [0.65,0.65,0.65];
            elseif value==3
                app.EditField_5.Enable = 'on';
                app.EditField_6.Enable = 'on';
                app.EditField_7.Enable = 'off';
                app.EditField_8.Enable = 'off';
                app.EditField_9.Enable = 'off';
                app.EditField_10.Enable = 'off';
                app.capteursButton_2.BackgroundColor = [0.65,0.65,0.65];
            elseif value==4
                app.EditField_5.Enable = 'on';
                app.EditField_6.Enable = 'on';
                app.EditField_7.Enable = 'on';
                app.EditField_8.Enable = 'on';
                app.EditField_9.Enable = 'off';
                app.EditField_10.Enable = 'off';
            elseif value==5
                app.EditField_5.Enable = 'on';
                app.EditField_6.Enable = 'on';
                app.EditField_7.Enable = 'on';
                app.EditField_8.Enable = 'on';
                app.EditField_9.Enable = 'on';
                app.EditField_10.Enable = 'on';
                app.capteursButton_3.BackgroundColor = [0.65,0.65,0.65];
            end
        end

        % Button pushed function: capteursButton
        function capteursButtonPushed(app, event)
            app.EditField_5.Enable = 'off';
            app.EditField_6.Enable = 'off';
            app.EditField_7.Enable = 'off';
            app.EditField_8.Enable = 'off';
            app.EditField_9.Enable = 'off';
            app.EditField_10.Enable = 'off';

            app.capteursButton.BackgroundColor = [0.65,0.65,0.65];
            app.capteursButton_2.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_3.BackgroundColor = [0.96,0.96,0.96];
            
            n = 2;
            alea = rand(2*n,1)*2;

            app.EditField.Value = alea(1);
            app.EditField_2.Value = alea(2);

            app.EditField_3.Value = alea(3);
            app.EditField_4.Value = alea(4);

            app.NombredecapteurSpinner.Value = n;
        end

        % Button pushed function: capteursButton_2
        function capteursButton_2Pushed(app, event)
            app.EditField_5.Enable = 'on';
            app.EditField_6.Enable = 'on';
            app.EditField_7.Enable = 'off';
            app.EditField_8.Enable = 'off';
            app.EditField_9.Enable = 'off';
            app.EditField_10.Enable = 'off';
            
            app.capteursButton.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_2.BackgroundColor = [0.65,0.65,0.65];
            app.capteursButton_3.BackgroundColor = [0.96,0.96,0.96];

            n = 3;
            alea = rand(2*n,1)*2;

            app.EditField.Value = alea(1);
            app.EditField_2.Value = alea(2);

            app.EditField_3.Value = alea(3);
            app.EditField_4.Value = alea(4);

            app.EditField_5.Value = alea(5);
            app.EditField_6.Value = alea(6);

            app.NombredecapteurSpinner.Value = n;
        end

        % Button pushed function: capteursButton_3
        function capteursButton_3Pushed(app, event)
            app.EditField_5.Enable = 'on';
            app.EditField_6.Enable = 'on';
            app.EditField_7.Enable = 'on';
            app.EditField_8.Enable = 'on';
            app.EditField_9.Enable = 'on';
            app.EditField_10.Enable = 'on';

            app.capteursButton.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_2.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_3.BackgroundColor = [0.65,0.65,0.65];
            
            n = 5;
            alea = rand(2*n,1)*2;

            app.EditField.Value = alea(1);
            app.EditField_2.Value = alea(2);

            app.EditField_3.Value = alea(3);
            app.EditField_4.Value = alea(4);

            app.EditField_5.Value = alea(5);
            app.EditField_6.Value = alea(6);

            app.EditField_7.Value = alea(7);
            app.EditField_8.Value = alea(8);

            app.EditField_9.Value = alea(9);
            app.EditField_10.Value = alea(10);

            app.NombredecapteurSpinner.Value = n;
        end

        % Button pushed function: TraitementButton
        function TraitementButtonPushed(app, event)
            global source1_data source2_data X Y Z M Ns Nc Nb i0 N t;
            UpdateMatrixM(app);
            Ns = length(M(1,:));
            Nc = length(M(:,1));
            Nb = app.NombredepointSlider.Value;
            i0 = app.Valeurdei0Slider.Value;

            X = [source1_data' ; source2_data'];
            Y = M*X;

            for j=1:Nc
                for k=1:Nc
                    GammaY_0(j,k) = Intercorrelation(app, Y(j,:),Y(k,:),N,1);
                    GammaY_i0(j,k) = Intercorrelation(app, Y(j,:),Y(k,:),N,i0+1);
                end
            end
            LambdaY_i0 = inv(GammaY_0)*GammaY_i0;

            Mat = eigtrivpd(app, LambdaY_i0);
            U = Mat(:,1:Ns);
            S = U.';
            Zp = S*Y;

            for j=1:Ns
                for k=1:Ns
                    GammaZp_0(j,k) = Intercorrelation(app, Zp(j,:),Zp(k,:),N,1);
                    GammaZp_i0(j,k) = Intercorrelation(app, Zp(j,:),Zp(k,:),N,i0+1);
                end
            end
            LambdaZp_i0 = inv(GammaZp_0)*GammaZp_i0;

            Mat = eigtrivpd(app, LambdaZp_i0);
            U = Mat;
            S = U.';
            Z = S*Zp;

            if Nc==2
                plot(app.Mixed1_Plot, t, Y(1,:));
                plot(app.Mixed2_Plot, t, Y(2,:));
                plot(app.Mixed3_Plot, t, zeros(1,N));
                plot(app.Mixed4_Plot, t, zeros(1,N));
                plot(app.Mixed5_Plot, t, zeros(1,N));
                app.ChoixdelecouteDropDown.Items = {'Source 1', 'Source 2', 'Separe 1', 'Separe 2', 'Melange 1', 'Melange 2'};
            elseif Nc==3
                plot(app.Mixed1_Plot, t, Y(1,:));
                plot(app.Mixed2_Plot, t, Y(2,:));
                plot(app.Mixed3_Plot, t, Y(3,:));
                plot(app.Mixed4_Plot, t, zeros(1,N));
                plot(app.Mixed5_Plot, t, zeros(1,N));
                app.ChoixdelecouteDropDown.Items = {'Source 1', 'Source 2', 'Separe 1', 'Separe 2', 'Melange 1', 'Melange 2', 'Melange 3'};
            elseif Nc==4
                plot(app.Mixed1_Plot, t, Y(1,:));
                plot(app.Mixed2_Plot, t, Y(2,:));
                plot(app.Mixed3_Plot, t, Y(3,:));
                plot(app.Mixed4_Plot, t, Y(4,:));
                plot(app.Mixed5_Plot, t, zeros(1,N));
                app.ChoixdelecouteDropDown.Items = {'Source 1', 'Source 2', 'Separe 1', 'Separe 2', 'Melange 1', 'Melange 2', 'Melange 3', 'Melange 4'};
            elseif Nc==5
                plot(app.Mixed1_Plot, t, Y(1,:));
                plot(app.Mixed2_Plot, t, Y(2,:));
                plot(app.Mixed3_Plot, t, Y(3,:));
                plot(app.Mixed4_Plot, t, Y(4,:));
                plot(app.Mixed5_Plot, t, Y(5,:));
                app.ChoixdelecouteDropDown.Items = {'Source 1', 'Source 2', 'Separe 1', 'Separe 2', 'Melange 1', 'Melange 2', 'Melange 3', 'Melange 4', 'Melange 5'};
            end

            selectedButton = app.AffichagedelasparationButtonGroup.SelectedObject;
            text = selectedButton.Text;
            if text=='Source séparée 1'
                plot(app.PlotSourceSplit, t, Z(1,:));
            elseif text=='Source séparée 2'
                plot(app.PlotSourceSplit, t, Z(2,:));
            end
            DisplayMessage(app, text);
        end

        % Value changed function: Valeurdei0Slider
        function Valeurdei0SliderValueChanged(app, event)
            value = app.Valeurdei0Slider.Value;
            app.Valeurdei0Slider.Value = round(value);
        end

        % Value changed function: NombredepointSlider
        function NombredepointSliderValueChanged(app, event)
            value = app.NombredepointSlider.Value;
            app.NombredepointSlider.Value = round(value);
        end

        % Menu selected function: ReinitialiserlessourceMenu
        function ReinitialiserlessourceMenuSelected(app, event)
            global source1_name source1_data source2_name source2_data GammaY LambdaY_i0 Fe Ns Nc N t T M X Y Z;
            
            data = load('handel.mat');
            source1_name = 'handel.mat';
            source1_data = data.y(1:N,1);
            set(app.Nomdelasource1TextArea, 'Value','');
            set(app.Nomdelasource1TextArea, 'Value',source1_name);

            data = load('laughter.mat');
            source2_name = 'laughter.mat';
            source2_data = data.y(1:N,1);
            set(app.Nomdelasource2TextArea, 'Value','');
            set(app.Nomdelasource2TextArea, 'Value',source2_name);

            selectedButton = app.AffichagedessourcesButtonGroup.SelectedObject;
            text = selectedButton.Text;
            if text=='Source 1'
                plot(app.PlotSource, t, source1_data);
            elseif text=='Source 2'
                plot(app.PlotSource, t, source2_data);
            end
        end

        % Menu selected function: ExempledematriceM2x2Menu
        function ExempledematriceM2x2MenuSelected(app, event)
            app.EditField.Value = 1;
            app.EditField_2.Value = 1;
            app.EditField_3.Value = 1.1;
            app.EditField_4.Value = 1.3;

            app.NombredecapteurSpinner.Value = 2;

            app.EditField_5.Enable = 'off';
            app.EditField_6.Enable = 'off';
            app.EditField_7.Enable = 'off';
            app.EditField_8.Enable = 'off';
            app.EditField_9.Enable = 'off';
            app.EditField_10.Enable = 'off';
            app.capteursButton.BackgroundColor = [0.65,0.65,0.65];
            app.capteursButton_2.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_3.BackgroundColor = [0.96,0.96,0.96];
        end

        % Menu selected function: ExempledematriceM3x2Menu
        function ExempledematriceM3x2MenuSelected(app, event)
            app.EditField.Value = 1;
            app.EditField_2.Value = 1;
            app.EditField_3.Value = 1.8;
            app.EditField_4.Value = 1.3;
            app.EditField_5.Value = 0.55;
            app.EditField_6.Value = 1.8;

            app.NombredecapteurSpinner.Value = 3;

            app.EditField_5.Enable = 'on';
            app.EditField_6.Enable = 'on';
            app.EditField_7.Enable = 'off';
            app.EditField_8.Enable = 'off';
            app.EditField_9.Enable = 'off';
            app.EditField_10.Enable = 'off';
            app.capteursButton.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_2.BackgroundColor = [0.65,0.65,0.65];
            app.capteursButton_3.BackgroundColor = [0.96,0.96,0.96];
        end

        % Menu selected function: ExempledematriceM5x2Menu
        function ExempledematriceM5x2MenuSelected(app, event)
            app.EditField.Value = 1;
            app.EditField_2.Value = 1;
            app.EditField_3.Value = 1.8;
            app.EditField_4.Value = 1.3;
            app.EditField_5.Value = 0.55;
            app.EditField_6.Value = 1.8;
            app.EditField_7.Value = 1.2;
            app.EditField_8.Value = 0.8;
            app.EditField_9.Value = 1;
            app.EditField_10.Value = 1.25;

            app.NombredecapteurSpinner.Value = 5;

            app.EditField_5.Enable = 'on';
            app.EditField_6.Enable = 'on';
            app.EditField_7.Enable = 'on';
            app.EditField_8.Enable = 'on';
            app.EditField_9.Enable = 'on';
            app.EditField_10.Enable = 'on';
            app.capteursButton.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_2.BackgroundColor = [0.96,0.96,0.96];
            app.capteursButton_3.BackgroundColor = [0.65,0.65,0.65];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SparationaveugledesourcesUIFigure = uifigure('Visible', 'off');
            app.SparationaveugledesourcesUIFigure.Name = 'Séparation aveugle de sources';
            app.SparationaveugledesourcesUIFigure.Icon = fullfile(pathToMLAPP, 'Calculator-Icon-Background-PNG-Image.png');
            app.ChoixdesourceMenu = uimenu(app.SparationaveugledesourcesUIFigure);
            app.ChoixdesourceMenu.Text = 'Choix de source';
            app.ChoixdesourceMenu.Tag = 'SourceMenu_Button';
            app.Source1Menu = uimenu(app.ChoixdesourceMenu);
            app.Source1Menu.MenuSelectedFcn = createCallbackFcn(app, @Source1MenuSelected, true);
            app.Source1Menu.Text = 'Source 1';
            app.Source1Menu.Tag = 'SourceMenu_Button1';
            app.Source2Menu = uimenu(app.ChoixdesourceMenu);
            app.Source2Menu.MenuSelectedFcn = createCallbackFcn(app, @Source2MenuSelected, true);
            app.Source2Menu.Text = 'Source 2';
            app.Source2Menu.Tag = 'SourceMenu_Button2';
            app.ParametrageMenu = uimenu(app.SparationaveugledesourcesUIFigure);
            app.ParametrageMenu.Text = 'Parametrage';
            app.ReinitialiserlessourceMenu = uimenu(app.ParametrageMenu);
            app.ReinitialiserlessourceMenu.MenuSelectedFcn = createCallbackFcn(app, @ReinitialiserlessourceMenuSelected, true);
            app.ReinitialiserlessourceMenu.Text = 'Reinitialiser les source';
            app.ExempledematriceM2x2Menu = uimenu(app.ParametrageMenu);
            app.ExempledematriceM2x2Menu.MenuSelectedFcn = createCallbackFcn(app, @ExempledematriceM2x2MenuSelected, true);
            app.ExempledematriceM2x2Menu.Text = 'Exemple de matrice M 2x2';
            app.ExempledematriceM3x2Menu = uimenu(app.ParametrageMenu);
            app.ExempledematriceM3x2Menu.MenuSelectedFcn = createCallbackFcn(app, @ExempledematriceM3x2MenuSelected, true);
            app.ExempledematriceM3x2Menu.Text = 'Exemple de matrice M 3x2';
            app.ExempledematriceM5x2Menu = uimenu(app.ParametrageMenu);
            app.ExempledematriceM5x2Menu.MenuSelectedFcn = createCallbackFcn(app, @ExempledematriceM5x2MenuSelected, true);
            app.ExempledematriceM5x2Menu.Text = 'Exemple de matrice M 5x2';
            app.GridLayout = uigridlayout(app.SparationaveugledesourcesUIFigure);
            app.PlotSource = uiaxes(app.GridLayout);
            title(app.PlotSource, 'Source')
            xlabel(app.PlotSource, 'Temps (s)')
            ylabel(app.PlotSource, 'Amplitude')
            zlabel(app.PlotSource, 'Z')
            app.PlotSource.Tag = 'PlotSource';
            app.PlotSourceSplit = uiaxes(app.GridLayout);
            title(app.PlotSourceSplit, 'Source séparée')
            xlabel(app.PlotSourceSplit, 'Temps (s)')
            ylabel(app.PlotSourceSplit, 'Amplitude')
            zlabel(app.PlotSourceSplit, 'Z')
            app.PlotSourceSplit.Tag = 'PlotSourceSplit';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AffichagedessourcesButtonGroup = uibuttongroup(app.GridLayout2);
            app.AffichagedessourcesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AffichagedessourcesButtonGroupSelectionChanged, true);
            app.AffichagedessourcesButtonGroup.Title = 'Affichage des sources';
            app.AffichagedessourcesButtonGroup.Tag = 'ChoixAffichage1';
            app.Source1Button = uiradiobutton(app.AffichagedessourcesButtonGroup);
            app.Source1Button.Tag = 'Source1_RadioButton1';
            app.Source1Button.Text = 'Source 1';
            app.Source1Button.Value = true;
            app.Source2Button = uiradiobutton(app.AffichagedessourcesButtonGroup);
            app.Source2Button.Tag = 'Source2_RadioButton1';
            app.Source2Button.Text = 'Source 2';
            app.AffichagedelasparationButtonGroup = uibuttongroup(app.GridLayout2);
            app.AffichagedelasparationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AffichagedelasparationButtonGroupSelectionChanged, true);
            app.AffichagedelasparationButtonGroup.Title = 'Affichage de la séparation';
            app.AffichagedelasparationButtonGroup.Tag = 'ChoixAffichage2';
            app.Sourcespare1Button = uiradiobutton(app.AffichagedelasparationButtonGroup);
            app.Sourcespare1Button.Tag = 'SourceSplit1_RadioButton1';
            app.Sourcespare1Button.Text = 'Source séparée 1';
            app.Sourcespare1Button.Value = true;
            app.Sourcespare2Button = uiradiobutton(app.AffichagedelasparationButtonGroup);
            app.Sourcespare2Button.Tag = 'SourceSplit2_RadioButton1';
            app.Sourcespare2Button.Text = 'Source séparée 2';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.Nomdelasource2TextAreaLabel = uilabel(app.GridLayout4);
            app.Nomdelasource2TextAreaLabel.Text = 'Nom de la source 2';
            app.Nomdelasource2TextArea = uitextarea(app.GridLayout4);
            app.Nomdelasource2TextArea.Tag = 'textNameSource2';
            app.Nomdelasource2TextArea.Editable = 'off';
            app.Nomdelasource1TextAreaLabel = uilabel(app.GridLayout4);
            app.Nomdelasource1TextAreaLabel.Text = 'Nom de la source 1';
            app.Nomdelasource1TextArea = uitextarea(app.GridLayout4);
            app.Nomdelasource1TextArea.Tag = 'textNameSource1';
            app.Nomdelasource1TextArea.Editable = 'off';
            app.TextArea = uitextarea(app.GridLayout2);
            app.TextArea.Tag = 'TextArea';
            app.TextArea.Editable = 'off';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.ChoixdelecouteDropDownLabel = uilabel(app.GridLayout5);
            app.ChoixdelecouteDropDownLabel.Text = 'Choix de l''ecoute';
            app.ChoixdelecouteDropDown = uidropdown(app.GridLayout5);
            app.ChoixdelecouteDropDown.Items = {'Source 1', 'Source 2', 'Separe 1', 'Separe 2', 'Melange 1', 'Melange 2'};
            app.ChoixdelecouteDropDown.Tag = 'Play_Selection';
            app.ChoixdelecouteDropDown.Value = 'Source 1';
            app.EcouteButton = uibutton(app.GridLayout5, 'push');
            app.EcouteButton.ButtonPushedFcn = createCallbackFcn(app, @EcouteButtonPushed, true);
            app.EcouteButton.Tag = 'Play_Button';
            app.EcouteButton.Text = 'Ecoute';
            app.Lamp = uilamp(app.GridLayout5);
            app.Lamp.Tag = 'Play_Led';
            app.Lamp.Enable = 'off';
            app.VolumeSlider = uislider(app.GridLayout5);
            app.VolumeSlider.Limits = [0 3];
            app.VolumeSlider.Value = 1;
            app.VolumeSliderLabel = uilabel(app.GridLayout5);
            app.VolumeSliderLabel.Text = 'Volume';
            app.MatricedemelangePanel = uipanel(app.GridLayout2);
            app.MatricedemelangePanel.Title = 'Matrice de melange';
            app.GridLayout7 = uigridlayout(app.MatricedemelangePanel);
            app.EditField = uieditfield(app.GridLayout7, 'numeric');
            app.EditField.Limits = [0 2];
            app.EditField.Tag = 'M11';
            app.EditField_2 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_2.Limits = [0 2];
            app.EditField_2.Tag = 'M12';
            app.EditField_3 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_3.Limits = [0 2];
            app.EditField_3.Tag = 'M21';
            app.EditField_4 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_4.Limits = [0 2];
            app.EditField_4.Tag = 'M22';
            app.EditField_5 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_5.Limits = [0 2];
            app.EditField_5.Tag = 'M31';
            app.EditField_5.Enable = 'off';
            app.EditField_6 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_6.Limits = [0 2];
            app.EditField_6.Tag = 'M32';
            app.EditField_6.Enable = 'off';
            app.EditField_7 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_7.Limits = [0 2];
            app.EditField_7.Tag = 'M41';
            app.EditField_7.Enable = 'off';
            app.EditField_8 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_8.Limits = [0 2];
            app.EditField_8.Tag = 'M42';
            app.EditField_8.Enable = 'off';
            app.EditField_9 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_9.Limits = [0 2];
            app.EditField_9.Tag = 'M51';
            app.EditField_9.Enable = 'off';
            app.EditField_10 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_10.Limits = [0 2];
            app.EditField_10.Tag = 'M52';
            app.EditField_10.Enable = 'off';
            app.MatricealeatoirePanel = uipanel(app.GridLayout2);
            app.MatricealeatoirePanel.Title = 'Matrice aleatoire';
            app.GridLayout6 = uigridlayout(app.MatricealeatoirePanel);
            app.capteursButton = uibutton(app.GridLayout6, 'push');
            app.capteursButton.ButtonPushedFcn = createCallbackFcn(app, @capteursButtonPushed, true);
            app.capteursButton.Tag = 'M2cap';
            app.capteursButton.Text = '2 capteurs';
            app.capteursButton_2 = uibutton(app.GridLayout6, 'push');
            app.capteursButton_2.ButtonPushedFcn = createCallbackFcn(app, @capteursButton_2Pushed, true);
            app.capteursButton_2.Tag = 'M3cap';
            app.capteursButton_2.Text = '3 capteurs';
            app.capteursButton_3 = uibutton(app.GridLayout6, 'push');
            app.capteursButton_3.ButtonPushedFcn = createCallbackFcn(app, @capteursButton_3Pushed, true);
            app.capteursButton_3.Tag = 'M5cap';
            app.capteursButton_3.Text = '5 capteurs';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.NombredecapteurSpinnerLabel = uilabel(app.GridLayout8);
            app.NombredecapteurSpinnerLabel.Text = 'Nombre de capteur';
            app.NombredecapteurSpinner = uispinner(app.GridLayout8);
            app.NombredecapteurSpinner.Limits = [2 5];
            app.NombredecapteurSpinner.ValueChangedFcn = createCallbackFcn(app, @NombredecapteurSpinnerValueChanged, true);
            app.NombredecapteurSpinner.Editable = 'off';
            app.NombredecapteurSpinner.Value = 2;
            app.TraitementButton = uibutton(app.GridLayout8, 'push');
            app.TraitementButton.ButtonPushedFcn = createCallbackFcn(app, @TraitementButtonPushed, true);
            app.TraitementButton.Tag = 'Process_Button';
            app.TraitementButton.Text = 'Traitement';
            app.GridLayout10 = uigridlayout(app.GridLayout2);
            app.NombredepointSliderLabel = uilabel(app.GridLayout10);
            app.NombredepointSliderLabel.Text = 'Nombre de point';
            app.NombredepointSlider = uislider(app.GridLayout10);
            app.NombredepointSlider.Limits = [100 900];
            app.NombredepointSlider.ValueChangedFcn = createCallbackFcn(app, @NombredepointSliderValueChanged, true);
            app.NombredepointSlider.Tag = 'Nb_Slidder';
            app.NombredepointSlider.Value = 400;
            app.Valeurdei0SliderLabel = uilabel(app.GridLayout10);
            app.Valeurdei0SliderLabel.Text = 'Valeur de i0';
            app.Valeurdei0Slider = uislider(app.GridLayout10);
            app.Valeurdei0Slider.Limits = [1 11];
            app.Valeurdei0Slider.ValueChangedFcn = createCallbackFcn(app, @Valeurdei0SliderValueChanged, true);
            app.Valeurdei0Slider.Tag = 'I0_Slidder';
            app.Valeurdei0Slider.Value = 2;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Plot1Tab = uitab(app.TabGroup);
            app.Plot1Tab.Title = 'Plot 1';
            app.Plot1Tab.Tag = 'Mixed1_Menu';
            app.Mixed1_Plot = uiaxes(app.Plot1Tab);
            title(app.Mixed1_Plot, 'Melange 1')
            xlabel(app.Mixed1_Plot, 'Temps (s)')
            ylabel(app.Mixed1_Plot, 'Amplitude')
            zlabel(app.Mixed1_Plot, 'Z')
            app.Mixed1_Plot.Tag = 'Mixed1_Plot';
            app.Plot2Tab = uitab(app.TabGroup);
            app.Plot2Tab.Title = 'Plot 2';
            app.Plot2Tab.Tag = 'Mixed2_Menu';
            app.Mixed2_Plot = uiaxes(app.Plot2Tab);
            title(app.Mixed2_Plot, 'Melange 2')
            xlabel(app.Mixed2_Plot, 'Temps (s)')
            ylabel(app.Mixed2_Plot, 'Amplitude')
            zlabel(app.Mixed2_Plot, 'Z')
            app.Mixed2_Plot.Tag = 'Mixed2_Plot';
            app.Plot3Tab = uitab(app.TabGroup);
            app.Plot3Tab.Title = 'Plot 3';
            app.Plot3Tab.Tag = 'Mixed3_Menu';
            app.Mixed3_Plot = uiaxes(app.Plot3Tab);
            title(app.Mixed3_Plot, 'Melange 3')
            xlabel(app.Mixed3_Plot, 'Temps (s)')
            ylabel(app.Mixed3_Plot, 'Amplitude')
            zlabel(app.Mixed3_Plot, 'Z')
            app.Mixed3_Plot.Tag = 'Mixed3_Plot';
            app.Plot4Tab = uitab(app.TabGroup);
            app.Plot4Tab.Title = 'Plot 4';
            app.Plot4Tab.Tag = 'Mixed4_Menu';
            app.Mixed4_Plot = uiaxes(app.Plot4Tab);
            title(app.Mixed4_Plot, 'Melange 4')
            xlabel(app.Mixed4_Plot, 'Temps (s)')
            ylabel(app.Mixed4_Plot, 'Amplitude')
            zlabel(app.Mixed4_Plot, 'Z')
            app.Mixed4_Plot.Tag = 'Mixed4_Plot';
            app.Plot5Tab = uitab(app.TabGroup);
            app.Plot5Tab.Title = 'Plot 5';
            app.Plot5Tab.Tag = 'Mixed5_Menu';
            app.Mixed5_Plot = uiaxes(app.Plot5Tab);
            title(app.Mixed5_Plot, 'Melange 5')
            xlabel(app.Mixed5_Plot, 'Temps (s)')
            ylabel(app.Mixed5_Plot, 'Amplitude')
            zlabel(app.Mixed5_Plot, 'Z')
            app.Mixed5_Plot.Tag = 'Mixed5_Plot';
            % Show the figure after all components are created
            app.SparationaveugledesourcesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Separation_aveugle_de_sources

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SparationaveugledesourcesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SparationaveugledesourcesUIFigure)
        end
    end
end